classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        BottomPanel                     matlab.ui.container.Panel
        TimeScope6                      matlab.ui.scope.TimeScope
        TimeScope7                      matlab.ui.scope.TimeScope
        LoggedSignalLabel               matlab.ui.control.Label
        TimeScope5                      matlab.ui.scope.TimeScope
        TimeScope3                      matlab.ui.scope.TimeScope
        TimeScope2                      matlab.ui.scope.TimeScope
        TimeScope                       matlab.ui.scope.TimeScope
        ScopesDropDown                  matlab.ui.control.DropDown
        ScopesDropDownLabel             matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        OperatingSystemSettingsTab      matlab.ui.container.Tab
        TopPanel                        matlab.ui.container.Panel
        SimulationProgress              simulink.ui.control.SimulationProgress
        Button2                         matlab.ui.control.Button
        SimulationControls2             simulink.ui.control.SimulationControls
        ConfigurationsPanel             matlab.ui.container.Panel
        TopologyDropDown                matlab.ui.control.DropDown
        SelectthedesiredHybridPowerSplitStrategyLabel  matlab.ui.control.Label
        SelectstrategiesDropDownLabel   matlab.ui.control.Label
        SelecttheAircraftsDropDownLabel  matlab.ui.control.Label
        AircraftsDropDown               matlab.ui.control.DropDown
        SelectthedesiredAircraftandviewitsspecificationsLabel  matlab.ui.control.Label
        MaxAltitudeEditField            matlab.ui.control.NumericEditField
        MaxAltitudemEditFieldLabel      matlab.ui.control.Label
        MaxSpeedEditField               matlab.ui.control.NumericEditField
        MaxSpeedmsEditFieldLabel        matlab.ui.control.Label
        FlightDurationEditField         matlab.ui.control.NumericEditField
        FlightDurationsEditFieldLabel   matlab.ui.control.Label
        PredefinedFlightProfileDropDown  matlab.ui.control.DropDown
        SelectthedesiredflightprofileLabel  matlab.ui.control.Label
        AircraftSpecsTable              matlab.ui.control.Table
        FlightProfileAxes               matlab.ui.control.UIAxes
        ComponentsTab                   matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        EPUTab                          matlab.ui.container.Tab
        ElectricPropulsionUnitSettingsPanel  matlab.ui.container.Panel
        MotorScalingFactorEditField     matlab.ui.control.NumericEditField
        MotorScalingFactorEditFieldLabel  matlab.ui.control.Label
        AuxillaryPowerEditField         matlab.ui.control.NumericEditField
        AuxillaryPowerEditFieldLabel    matlab.ui.control.Label
        GearRatioEditField              matlab.ui.control.NumericEditField
        GearRatioEditFieldLabel         matlab.ui.control.Label
        OtherParametersLabel            matlab.ui.control.Label
        MotorNumberEditField            matlab.ui.control.NumericEditField
        MotorNumberEditFieldLabel       matlab.ui.control.Label
        SelecttheNumberofMotorsLabel    matlab.ui.control.Label
        MotorTorqueNmEditField          matlab.ui.control.NumericEditField
        MotorTorqueNmEditFieldLabel     matlab.ui.control.Label
        MotorOperatingSpeedrpmEditField  matlab.ui.control.NumericEditField
        MotorOperatingSpeedrpmEditFieldLabel  matlab.ui.control.Label
        MotorTypesDropDown              matlab.ui.control.DropDown
        MotorTypesDropDownLabel         matlab.ui.control.Label
        SelecttheMotorTypesLabel        matlab.ui.control.Label
        MotorEffAxes                    matlab.ui.control.UIAxes
        FUELCELLTab                     matlab.ui.container.Tab
        FuelCellSettingsPanel           matlab.ui.container.Panel
        OperatingTemperatureCelsiusEditField  matlab.ui.control.NumericEditField
        OperatingTemperatureCelsiusEditFieldLabel  matlab.ui.control.Label
        MaxOperatingPointAm2VEditField  matlab.ui.control.EditField
        MaxOperatingPointAm2VEditFieldLabel  matlab.ui.control.Label
        Voltageat0AVEditField           matlab.ui.control.NumericEditField
        Voltageat0AVEditFieldLabel      matlab.ui.control.Label
        FuelCellTypesDropDown           matlab.ui.control.DropDown
        FuelCellTypesDropDownLabel      matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        SelectthedesiredFuelCellTypeLabel  matlab.ui.control.Label
        FCplot                          matlab.ui.control.UIAxes
        BATTERYEnergyConsumptionTab     matlab.ui.container.Tab
        BatterySettingsPanel            matlab.ui.container.Panel
        BattTable                       matlab.ui.control.Table
        NumberofSeriesCellsEditField    matlab.ui.control.NumericEditField
        NumberofSeriesCellsEditFieldLabel  matlab.ui.control.Label
        NumberofParallelCellsEditField  matlab.ui.control.NumericEditField
        NumberofParallelCellsEditFieldLabel  matlab.ui.control.Label
        ParametersLabel                 matlab.ui.control.Label
        InitialSOCEditField             matlab.ui.control.NumericEditField
        InitialSOCEditFieldLabel        matlab.ui.control.Label
        BatteryTypesDropDown            matlab.ui.control.DropDown
        BatteryTypesDropDownLabel       matlab.ui.control.Label
        SelectthedesiredBatteryTypeandviewitspropertiesbelowLabel  matlab.ui.control.Label
        Battplot                        matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
    
     IsExpanded logical = false     % start collapsed
     TopRatioMin double = 0.05   % at least 30% of total height for top
     BottomRatioMin double = 0.25 % at least 25% for bottom
     BottomShare   double = 0.7  % target share for bottom when expanded

     IsExpanded_fp logical = false     % start collapsed
     TopRatioMin_fp double = 0.05   % at least 30% of total height for top
     BottomRatioMin_fp double = 0.2% at least 25% for bottom
     BottomShare_fp   double = 0.7  % target share for bottom when expanded
     isExpanded_but logical = false
                collapsedPos  double = [100 100 659 637]
                expandedPos  double = [100 100 1500 700]   % your expanded size
           
     
    end
    
    methods (Access = private)
        
        function layoutPanels(app)
        % Use TopPanel's parent as the container (tab/figure/panel)
       container = app.TopPanel.Parent;
    pos = container.Position; % [x y w h]
    W = pos(3);
    H = pos(4);
    if H <= 0 || W <= 0, return; end

    if app.IsExpanded
        % Flexible min heights in pixels (based on current container height)
        minTop    = app.TopRatioMin    * H;
        minBottom = app.BottomRatioMin * H;

        % Target bottom height based on share, but keep within min/max limits
        bottomH = max(minBottom, round(app.BottomShare * H));
        bottomH = min(bottomH, H - minTop);
    else
        bottomH = 0; % collapsed
    end

    topH = H - bottomH;

    % Apply positions
    %app.b_Panel.Visible  = app.IsExpanded;
   % app.b_Panel.Position = [0, 0, W, max(0, bottomH)];
    app.TopPanel.Position    = [0, bottomH, W, max(0, topH)];
        end
       
       
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             drawnow
       %      app.b_Panel.Parent = app.TopPanel.Parent;
    % Start collapsed
            app.IsExpanded = false;
            % app.b_Panel.Visible   = 'off';
         %    app.CollapseButton.Visible = 'off';
           % app.ExpandButton.Visible   = 'on';
            app.layoutPanels();
             app.TimeScope6.Visible = 'off';
              app.TimeScope3.Visible = 'off';
              app.TimeScope2.Visible = 'off';
              app.BottomPanel.Visible='off';
               app.TimeScope7.Visible = 'off';
                
              
    % Make children scale with their parent panels
           
            AircraftsDropDownValueChanged(app);
            PredefinedFlightProfileDropDownValueChanged(app);
            MotorTypesDropDownValueChanged(app);
            FuelCellTypesDropDownValueChanged(app);
            BatteryTypesDropDownValueChanged(app);
            
        end

        % Value changed function: PredefinedFlightProfileDropDown
        function PredefinedFlightProfileDropDownValueChanged(app, event)
            value = app.PredefinedFlightProfileDropDown.Value;
            
              
    % 2. Get profile struct using your existing function
            fp = getFlightProfile(value);
            
 
    % 3. Assign all required variables to base workspace
        assignin('base','missionProfile',      fp);
        assignin('base','FlightProfileType',   fp.type);
        assignin('base','FlightProfileLength', fp.FlightProfileLength);
        assignin('base','speed',               fp.speed);
        assignin('base','time',                fp.time);
        assignin('base','flap_setting',fp.flap_setting);
        assignin('base','landing_gear',fp.landing_gear);
        if isfield(fp,'altitude')
            assignin('base','altitude', fp.altitude);
        end
        if isfield(fp,'distance')
            assignin('base','distance', fp.distance);
        end
        if isfield(fp,'Phaseid')
            assignin('base','phaseid', fp.Phaseid);
        end
        if isfield(fp,'acceleration')
            assignin('base','acceleration', fp.acceleration);
        end
        if isfield(fp,'flightpathangle')
            assignin('base','flightpathangle', fp.flightpathangle);
            assignin('base','value', 0);  
        else
            %assignin('base','flightpathangle', linspace(0,1,fp.FlightProfileLength + 1));
            assignin('base','flightpathangle', linspace(0, 1, length(fp.time)));

            assignin('base','value', 1);
        end
        %4. compute display info
        maxLength=fp.FlightProfileLength; %seconds
        maxSpeed=max(fp.speed(:)); %m/s
        maxHeight=max(fp.altitude(:)); %m
        % After calculating maxLength and maxSpeed
        %infoStr = sprintf('FlightDuration = %ds\nMaxSpeed = %.1f m/s\nMaxHeight=  %.1f m/s', maxLength, maxSpeed,maxHeight);

        % Set the label text in the app
        %app.InfoLabel.Text = infoStr;
        app.FlightDurationEditField.Value = maxLength;
        app.MaxSpeedEditField.Value = maxSpeed;
        app.MaxAltitudeEditField.Value=maxHeight;
        % Clear old plot
        cla(app.FlightProfileAxes, 'reset'); 

        %hold(app.FlightProfileAxes, 'on');

% Plot Speed vs Distance (if distance exists)
        if isfield(fp, 'distance') && isfield(fp, 'speed')
            plot(app.FlightProfileAxes, fp.distance, fp.speed, '-b', 'LineWidth', 1.5);
        end

% Plot Altitude vs Distance (if available)
        if isfield(fp, 'distance') && isfield(fp, 'altitude')
            yyaxis(app.FlightProfileAxes, 'right');
            plot(app.FlightProfileAxes, fp.distance, fp.altitude, '-r', 'LineWidth', 1.5);
        end

% Update axis labels and title
        xlabel(app.FlightProfileAxes, 'Distance (m)');
        yyaxis(app.FlightProfileAxes, 'left');
        ylabel(app.FlightProfileAxes, 'Speed (m/s)');
        yyaxis(app.FlightProfileAxes, 'right');
        ylabel(app.FlightProfileAxes, 'Altitude (m)');
        title(app.FlightProfileAxes, 'Speed and Altitude vs Distance');

        legend(app.FlightProfileAxes, {'Speed', 'Altitude'}, 'Location', 'best');
        hold(app.FlightProfileAxes, 'off');
 
        end

        % Value changed function: AircraftsDropDown
        function AircraftsDropDownValueChanged(app, event)
            aircraftName = app.AircraftsDropDown.Value;
            as = initializeaircraft(aircraftName);

            % 3. Send full struct to base workspace
            assignin('base', 'aircraftStruct', as);

            % 4. Send selected key fields to base
            assignin('base', 'type', as.type);
            % 3. Build table data as 2-column cell array
            specData = {
    'Aircraft Type',            as.type;
    'Cruising AirSpeed (m/s)',  num2str(as.trueairspeed);
    'Empty Mass (kg)',          num2str(as.m0);
    'Payload Mass (kg)',        num2str(as.payloadmass);
    'Max Takeoff Mass (kg)',    num2str(as.m1);
    'Max Landing Mass (kg)',    num2str(as.m2);
    'Wing Area (m^2)',          num2str(as.Sw);
    'Wing Span (m)',            num2str(as.Bw);
    'Propeller Type',           as.propeller;
    'Propeller Blades',         num2str(as.Bp);
    'Propeller Diameter (m)',   num2str(as.Dp);
    'Gravity (m/s^2)',          num2str(as.g);
            };

            % 4. Display in UITable
            app.AircraftSpecsTable.Data = specData;
            
        end

        % Value changed function: TopologyDropDown
        function TopologyDropDownValueChanged(app, event)
            % Step 1: Get selected string
            topologyName = app.TopologyDropDown.Value;

    % Step 2: Define the map
            topologyMap = containers.Map(...
            {'peak_assist', ...
            'fixed_split','fuelcell_baseline',  'battery_baseline'}, ...
             [1, 2, 3, 4]);

    % Step 3: Convert to ID
        if topologyMap.isKey(topologyName)
            topologyID = topologyMap(topologyName);
        else
            uialert(app.UIFigure, 'Invalid topology selected.', 'Error');
            return;
        end

    % Step 4: Assign to Simulink (base workspace)
        assignin('base', 'topology', topologyID);

            
        end

        % Callback function: AuxillaryPowerEditField, GearRatioEditField, 
        % ...and 6 other components
        function MotorTypesDropDownValueChanged(app, event)
           motorType=app.MotorTypesDropDown.Value;
           scalingParam=app.MotorScalingFactorEditField.Value;
           motorNo=app.MotorNumberEditField.Value;
           gear_ratio=app.GearRatioEditField.Value;
           Power_offtake=app.AuxillaryPowerEditField.Value;
            assignin('base','gear_ratio', gear_ratio);
            assignin('base','motor_no', motorNo);
            assignin('base','Power_offtake', Power_offtake);
            em = init_EPU(motorType, scalingParam);
            em.gear_ratio = gear_ratio;

            assignin('base','em', em);
            assignin('base','scaling', em.scaling);

            % Extract info
            peakTorque     = max(em.peak_torque);
            operatingSpeed = max(em.peak_speed);
            nominalTorque  = max(em.nomi_torque);
            app.MotorOperatingSpeedrpmEditField.Value=operatingSpeed;
            app.MotorTorqueNmEditField.Value=nominalTorque;

            cla(app.MotorEffAxes, 'reset'); 
            [S, T] = meshgrid(em.nomi_speed_eff, em.nomi_torque_eff);

% Plot a 3D surface of efficiency
            surf(app.MotorEffAxes, S, T, em.Efficiency);

% Make it prettier
            shading(app.MotorEffAxes, 'interp');   % smooth color shading
            colormap(app.MotorEffAxes, 'parula');  % same colormap as before
            colorbar(app.MotorEffAxes);

% Style axes
            xlabel(app.MotorEffAxes, 'Speed (rpm)', 'FontWeight', 'bold');
            ylabel(app.MotorEffAxes, 'Torque (Nm)', 'FontWeight', 'bold');
            zlabel(app.MotorEffAxes, 'Efficiency (%)', 'FontWeight', 'bold');
            title(app.MotorEffAxes, 'Motor Efficiency Surface', 'FontWeight', 'bold');

            grid(app.MotorEffAxes, 'on');
            view(app.MotorEffAxes, 45, 30);   % set 3D view angle

        end

        % Callback function: FuelCellTypesDropDown, 
        % ...and 4 other components
        function FuelCellTypesDropDownValueChanged(app, event)
            fctypes = app.FuelCellTypesDropDown.Value;
            aircraftname=app.AircraftsDropDown.Value;
            fc=initializeFC(aircraftname,fctypes);
            %volume=app.VolumeofFuelCellStackLEditField.Value;
            Temp=app.OperatingTemperatureCelsiusEditField.Value;
            app.Voltageat0AVEditField.Value=fc.voltage(1);
            app.MaxOperatingPointAm2VEditField.Value = ...
             sprintf('%.2f , %.2f ', fc.currentdensity(end), fc.voltage(end));
            assignin('base','fc',fc);
            
           % assignin('base', 'volfc', volume);
            assignin('base','T_stack',Temp);
            cla(app.FCplot, 'reset'); 
            plot(app.FCplot, fc.currentdensity, fc.voltage, '-b', 'LineWidth', 1.5);
             xlabel(app.FCplot, 'Currentdensity (A/m^2)');
            
            ylabel(app.FCplot, 'Voltage (V)');
        
            title(app.FCplot, 'Fuel Cell i-V Curve Modeling');
        
            
        end

        % Value changed function: BatteryTypesDropDown, 
        % ...and 3 other components
        function BatteryTypesDropDownValueChanged(app, event)
            BatteryTypes = app.BatteryTypesDropDown.Value;
            batt=init_battery(BatteryTypes);
            assignin('base','batt',batt);
            SOC_init=app.InitialSOCEditField.Value;
            Np=app.NumberofParallelCellsEditField.Value;
            Ns=app.NumberofSeriesCellsEditField.Value;
            assignin('base','SOC_init',SOC_init);
            assignin('base','Np',Np);
            assignin('base','Ns',Ns);

            battData = {
    'Battery Type',            batt.type;
    'Capacity  (Ah)',  num2str(batt.Capacity_Ah);
    'Nominal Voltage (V)',          num2str(batt.nominal_V);
    'Maximum Voltage(V)',        num2str(batt.maxi_V); 
    'Cutoff Voltage (V)',    num2str(batt.cuttoff_V);
    'Maximum Pulse Discharge Current(A)',    num2str(batt.maxipulse_dischargecurrent);
    'Continuous Discharge Current (A)',          num2str(batt.continuous_dischargecurrent);
    'Weight (g)',            num2str(batt.weight_g);
            };
            app.BattTable.Data =battData;
            cla(app.Battplot, 'reset'); 
           % plot(app.Battplot, batt.SOC, batt.OCV, '-b', 'LineWidth', 1.5);
            % xlabel(app.Battplot, 'State of Charge (SOC) (%)');
            
            %ylabel(app.Battplot, 'Open Circuit Voltage (V)');
        
           % title(app.Battplot, 'i-V Curve Modeling');
            if isfield(batt, 'SOC') && isfield(batt, 'OCV')
             plot(app.Battplot, batt.SOC, batt.OCV, '-b', 'LineWidth', 1.5); 
           end

% Plot Altitude vs Distance (if available)
           if isfield(batt, 'SOC') && isfield(batt, 'R_discharge')
            yyaxis(app.Battplot, 'right');
            plot(app.Battplot, batt.SOC, batt.R_discharge/1000, '-r', 'LineWidth', 1.5);
          end

% Update axis labels and title
        xlabel(app.Battplot, 'State of Charge SOC (%)');
        yyaxis(app.Battplot, 'left');
        ylabel(app.Battplot, 'Open Circuit Voltage OCV (V)');
        yyaxis(app.Battplot, 'right');
        ylabel(app.Battplot, 'Discharge Resistance (mOhm)');
        title(app.Battplot, 'OCV & Discharge Resistance vs SOC');
        legend(app.Battplot, {'OCV', 'R Discharge'}, 'Location', 'best');
        hold(app.Battplot, 'off');
            
        end

        % Callback function
        function ExpandButtonPushed(app, event)
             app.IsExpanded = true;
    app.ExpandButton.Visible   = 'off';
    app.CollapseButton.Visible = 'on';
    app.layoutPanels();
        end

        % Callback function
        function CollapseButtonPushed(app, event)
             app.IsExpanded = false;
    app.CollapseButton.Visible = 'off';
    app.ExpandButton.Visible   = 'on';
    app.layoutPanels();
        end

        % Size changed function: OperatingSystemSettingsTab
        function OperatingSystemSettingsTabSizeChanged(app, event)
             drawnow 
            app.layoutPanels(); 
            
        end

        % Value changed function: ScopesDropDown
        function DropDownValueChanged(app, event)
            
  val = lower(string(app.ScopesDropDown.Value));

    % Hide both first (defensive)
    if ~isempty(app.TimeScope3) && isvalid(app.TimeScope3)
        app.TimeScope3.Visible = 'off';
    end
    if ~isempty(app.TimeScope6) && isvalid(app.TimeScope6)
        app.TimeScope6.Visible = 'off';
    end
     if ~isempty(app.TimeScope2) && isvalid(app.TimeScope2)
        app.TimeScope2.Visible = 'off';
    end
     if ~isempty(app.TimeScope7) && isvalid(app.TimeScope7)
        app.TimeScope7.Visible = 'off';
    end

    % Show the requested one
    switch val
        case "battery"
            if isvalid(app.TimeScope6)
                app.TimeScope6.Visible = 'on';
                 app.TimeScope7.Visible = 'on';
            end
        case "fuelcell"
            if isvalid(app.TimeScope3)&& isvalid(app.TimeScope2)
                app.TimeScope3.Visible = 'on';
                app.TimeScope2.Visible = 'on';
                

            end
    end
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            if ~app.isExpanded_but
        % Expand
                app.UIFigure.Position = app.expandedPos;
                app.BottomPanel.Visible = 'on';      % show your panel
                app.isExpanded_but = true;
            else
        % Collapse
                app.UIFigure.Position = app.collapsedPos;
                 app.BottomPanel.Visible = 'off';     % hide panel
                app.isExpanded_but = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OperatingSystemSettingsTab = uitab(app.TabGroup);
            app.OperatingSystemSettingsTab.SizeChangedFcn = createCallbackFcn(app, @OperatingSystemSettingsTabSizeChanged, true);
            app.OperatingSystemSettingsTab.Title = 'Operating System Settings';
            app.TopPanel = uipanel(app.OperatingSystemSettingsTab);
            app.FlightProfileAxes = uiaxes(app.TopPanel);
            title(app.FlightProfileAxes, 'Title')
            xlabel(app.FlightProfileAxes, 'X')
            ylabel(app.FlightProfileAxes, 'Y')
            zlabel(app.FlightProfileAxes, 'Z')
            app.AircraftSpecsTable = uitable(app.TopPanel);
            app.AircraftSpecsTable.ColumnName = {'Specifications'; 'Value'};
            app.AircraftSpecsTable.ColumnRearrangeable = 'on';
            app.AircraftSpecsTable.RowName = {};
            app.AircraftSpecsTable.SelectionType = 'row';
            app.AircraftSpecsTable.ColumnEditable = [false false];
            app.AircraftSpecsTable.Multiselect = 'off';
            app.ConfigurationsPanel = uipanel(app.TopPanel);
            app.ConfigurationsPanel.Title = 'Configurations';
            app.SelectthedesiredflightprofileLabel = uilabel(app.ConfigurationsPanel);
            app.SelectthedesiredflightprofileLabel.Text = 'Select the desired flight profile.';
            app.PredefinedFlightProfileDropDown = uidropdown(app.ConfigurationsPanel);
            app.PredefinedFlightProfileDropDown.Items = {'HAPSS_MissionProfile', 'NZ1', 'NZ2'};
            app.PredefinedFlightProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @PredefinedFlightProfileDropDownValueChanged, true);
            app.PredefinedFlightProfileDropDown.Value = 'HAPSS_MissionProfile';
            app.FlightDurationsEditFieldLabel = uilabel(app.ConfigurationsPanel);
            app.FlightDurationsEditFieldLabel.Text = 'Flight Duration (s)';
            app.FlightDurationEditField = uieditfield(app.ConfigurationsPanel, 'numeric');
            app.FlightDurationEditField.ValueDisplayFormat = '%.0f';
            app.FlightDurationEditField.Editable = 'off';
            app.MaxSpeedmsEditFieldLabel = uilabel(app.ConfigurationsPanel);
            app.MaxSpeedmsEditFieldLabel.Text = 'Max Speed (m/s)';
            app.MaxSpeedEditField = uieditfield(app.ConfigurationsPanel, 'numeric');
            app.MaxSpeedEditField.ValueDisplayFormat = '%.0f';
            app.MaxSpeedEditField.Editable = 'off';
            app.MaxAltitudemEditFieldLabel = uilabel(app.ConfigurationsPanel);
            app.MaxAltitudemEditFieldLabel.Text = ' Max Altitude (m)';
            app.MaxAltitudeEditField = uieditfield(app.ConfigurationsPanel, 'numeric');
            app.MaxAltitudeEditField.ValueDisplayFormat = '%.0f';
            app.MaxAltitudeEditField.Editable = 'off';
            app.SelectthedesiredAircraftandviewitsspecificationsLabel = uilabel(app.ConfigurationsPanel);
            app.SelectthedesiredAircraftandviewitsspecificationsLabel.Text = 'Select the desired Aircraft and view its specifications';
            app.AircraftsDropDown = uidropdown(app.ConfigurationsPanel);
            app.AircraftsDropDown.Items = {'HAPSS', 'AirBus A320 neo', 'ATR72-600', 'pipstrel velis'};
            app.AircraftsDropDown.ValueChangedFcn = createCallbackFcn(app, @AircraftsDropDownValueChanged, true);
            app.AircraftsDropDown.Value = 'HAPSS';
            app.SelecttheAircraftsDropDownLabel = uilabel(app.ConfigurationsPanel);
            app.SelecttheAircraftsDropDownLabel.Text = 'Select the Aircrafts';
            app.SelectstrategiesDropDownLabel = uilabel(app.ConfigurationsPanel);
            app.SelectstrategiesDropDownLabel.Text = 'Select strategies';
            app.SelectthedesiredHybridPowerSplitStrategyLabel = uilabel(app.ConfigurationsPanel);
            app.SelectthedesiredHybridPowerSplitStrategyLabel.Text = 'Select the desired Hybrid Power Split Strategy.';
            app.TopologyDropDown = uidropdown(app.ConfigurationsPanel);
            app.TopologyDropDown.Items = {'peak_assist', 'fixed_split', 'fuelcell_baseline', 'battery_baseline'};
            app.TopologyDropDown.ValueChangedFcn = createCallbackFcn(app, @TopologyDropDownValueChanged, true);
            app.TopologyDropDown.Value = 'peak_assist';
            app.SimulationControls2 = uisimcontrols(app.TopPanel);
            app.SimulationControls2.StartText = '';
            app.SimulationControls2.PauseText = '';
            app.SimulationControls2.ContinueText = '';
            app.SimulationControls2.StopText = '';
            app.SimulationControls2.Simulation = app.Simulation;
            app.Button2 = uibutton(app.TopPanel, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = '>';
            app.SimulationProgress = uisimprogress(app.TopPanel);
            app.SimulationProgress.Simulation = app.Simulation;
            app.ComponentsTab = uitab(app.TabGroup);
            app.ComponentsTab.Title = 'Components';
            app.TabGroup2 = uitabgroup(app.ComponentsTab);
            app.EPUTab = uitab(app.TabGroup2);
            app.EPUTab.Title = 'EPU';
            app.MotorEffAxes = uiaxes(app.EPUTab);
            title(app.MotorEffAxes, 'Motor Efficiency Map')
            xlabel(app.MotorEffAxes, 'X')
            ylabel(app.MotorEffAxes, 'Y')
            zlabel(app.MotorEffAxes, 'Z')
            app.MotorEffAxes.ButtonDownFcn = createCallbackFcn(app, @MotorTypesDropDownValueChanged, true);
            app.ElectricPropulsionUnitSettingsPanel = uipanel(app.EPUTab);
            app.ElectricPropulsionUnitSettingsPanel.Title = 'Electric Propulsion Unit Settings';
            app.SelecttheMotorTypesLabel = uilabel(app.ElectricPropulsionUnitSettingsPanel);
            app.SelecttheMotorTypesLabel.Text = 'Select the Motor Types.';
            app.MotorTypesDropDownLabel = uilabel(app.ElectricPropulsionUnitSettingsPanel);
            app.MotorTypesDropDownLabel.Text = 'Motor Types';
            app.MotorTypesDropDown = uidropdown(app.ElectricPropulsionUnitSettingsPanel);
            app.MotorTypesDropDown.Items = {'Leaf Motor', 'Other Motor'};
            app.MotorTypesDropDown.ValueChangedFcn = createCallbackFcn(app, @MotorTypesDropDownValueChanged, true);
            app.MotorTypesDropDown.Value = 'Leaf Motor';
            app.MotorOperatingSpeedrpmEditFieldLabel = uilabel(app.ElectricPropulsionUnitSettingsPanel);
            app.MotorOperatingSpeedrpmEditFieldLabel.Text = 'Motor Operating  Speed [rpm] ';
            app.MotorOperatingSpeedrpmEditField = uieditfield(app.ElectricPropulsionUnitSettingsPanel, 'numeric');
            app.MotorOperatingSpeedrpmEditField.ValueDisplayFormat = '%.0f';
            app.MotorOperatingSpeedrpmEditField.ValueChangedFcn = createCallbackFcn(app, @MotorTypesDropDownValueChanged, true);
            app.MotorOperatingSpeedrpmEditField.Editable = 'off';
            app.MotorTorqueNmEditFieldLabel = uilabel(app.ElectricPropulsionUnitSettingsPanel);
            app.MotorTorqueNmEditFieldLabel.Text = 'Motor Torque [Nm]';
            app.MotorTorqueNmEditField = uieditfield(app.ElectricPropulsionUnitSettingsPanel, 'numeric');
            app.MotorTorqueNmEditField.ValueDisplayFormat = '%.0f';
            app.MotorTorqueNmEditField.ValueChangedFcn = createCallbackFcn(app, @MotorTypesDropDownValueChanged, true);
            app.MotorTorqueNmEditField.Editable = 'off';
            app.SelecttheNumberofMotorsLabel = uilabel(app.ElectricPropulsionUnitSettingsPanel);
            app.SelecttheNumberofMotorsLabel.Text = 'Select the Number of Motors.';
            app.MotorNumberEditFieldLabel = uilabel(app.ElectricPropulsionUnitSettingsPanel);
            app.MotorNumberEditFieldLabel.Text = 'Motor Number [-]';
            app.MotorNumberEditField = uieditfield(app.ElectricPropulsionUnitSettingsPanel, 'numeric');
            app.MotorNumberEditField.ValueChangedFcn = createCallbackFcn(app, @MotorTypesDropDownValueChanged, true);
            app.MotorNumberEditField.Value = 1;
            app.OtherParametersLabel = uilabel(app.ElectricPropulsionUnitSettingsPanel);
            app.OtherParametersLabel.Text = 'Other Parameters';
            app.GearRatioEditFieldLabel = uilabel(app.ElectricPropulsionUnitSettingsPanel);
            app.GearRatioEditFieldLabel.Text = 'Gear Ratio [-]';
            app.GearRatioEditField = uieditfield(app.ElectricPropulsionUnitSettingsPanel, 'numeric');
            app.GearRatioEditField.ValueChangedFcn = createCallbackFcn(app, @MotorTypesDropDownValueChanged, true);
            app.GearRatioEditField.Value = 5;
            app.AuxillaryPowerEditFieldLabel = uilabel(app.ElectricPropulsionUnitSettingsPanel);
            app.AuxillaryPowerEditFieldLabel.Text = 'Auxillary Power [ %]';
            app.AuxillaryPowerEditField = uieditfield(app.ElectricPropulsionUnitSettingsPanel, 'numeric');
            app.AuxillaryPowerEditField.ValueChangedFcn = createCallbackFcn(app, @MotorTypesDropDownValueChanged, true);
            app.AuxillaryPowerEditField.Value = 4;
            app.MotorScalingFactorEditFieldLabel = uilabel(app.ElectricPropulsionUnitSettingsPanel);
            app.MotorScalingFactorEditFieldLabel.Text = 'Motor Scaling Factor[%]';
            app.MotorScalingFactorEditField = uieditfield(app.ElectricPropulsionUnitSettingsPanel, 'numeric');
            app.MotorScalingFactorEditField.ValueChangedFcn = createCallbackFcn(app, @MotorTypesDropDownValueChanged, true);
            app.MotorScalingFactorEditField.Value = 50;
            app.FUELCELLTab = uitab(app.TabGroup2);
            app.FUELCELLTab.Title = 'FUEL CELL';
            app.FCplot = uiaxes(app.FUELCELLTab);
            title(app.FCplot, 'Title')
            xlabel(app.FCplot, 'X')
            ylabel(app.FCplot, 'Y')
            zlabel(app.FCplot, 'Z')
            app.FuelCellSettingsPanel = uipanel(app.FUELCELLTab);
            app.FuelCellSettingsPanel.Title = 'Fuel Cell Settings';
            app.SelectthedesiredFuelCellTypeLabel = uilabel(app.FuelCellSettingsPanel);
            app.SelectthedesiredFuelCellTypeLabel.Text = 'Select the desired Fuel Cell Type.';
            app.Label = uilabel(app.FuelCellSettingsPanel);
            app.Label.Text = '';
            app.FuelCellTypesDropDownLabel = uilabel(app.FuelCellSettingsPanel);
            app.FuelCellTypesDropDownLabel.Text = 'Fuel Cell Types';
            app.FuelCellTypesDropDown = uidropdown(app.FuelCellSettingsPanel);
            app.FuelCellTypesDropDown.Items = {'pem', 'sofc', 'afc'};
            app.FuelCellTypesDropDown.ValueChangedFcn = createCallbackFcn(app, @FuelCellTypesDropDownValueChanged, true);
            app.FuelCellTypesDropDown.Value = 'pem';
            app.Voltageat0AVEditFieldLabel = uilabel(app.FuelCellSettingsPanel);
            app.Voltageat0AVEditFieldLabel.Text = 'Voltage at 0A [V]';
            app.Voltageat0AVEditField = uieditfield(app.FuelCellSettingsPanel, 'numeric');
            app.Voltageat0AVEditField.ValueChangedFcn = createCallbackFcn(app, @FuelCellTypesDropDownValueChanged, true);
            app.MaxOperatingPointAm2VEditFieldLabel = uilabel(app.FuelCellSettingsPanel);
            app.MaxOperatingPointAm2VEditFieldLabel.Text = 'Max Operating Point [A/m^2, V]';
            app.MaxOperatingPointAm2VEditField = uieditfield(app.FuelCellSettingsPanel, 'text');
            app.MaxOperatingPointAm2VEditField.ValueChangedFcn = createCallbackFcn(app, @FuelCellTypesDropDownValueChanged, true);
            app.MaxOperatingPointAm2VEditField.ValueChangingFcn = createCallbackFcn(app, @FuelCellTypesDropDownValueChanged, true);
            app.OperatingTemperatureCelsiusEditFieldLabel = uilabel(app.FuelCellSettingsPanel);
            app.OperatingTemperatureCelsiusEditFieldLabel.Text = 'Operating Temperature [ Celsius ]';
            app.OperatingTemperatureCelsiusEditField = uieditfield(app.FuelCellSettingsPanel, 'numeric');
            app.OperatingTemperatureCelsiusEditField.ValueChangedFcn = createCallbackFcn(app, @FuelCellTypesDropDownValueChanged, true);
            app.OperatingTemperatureCelsiusEditField.Value = 81;
            app.BATTERYEnergyConsumptionTab = uitab(app.TabGroup2);
            app.BATTERYEnergyConsumptionTab.Title = 'BATTERY: Energy Consumption';
            app.Battplot = uiaxes(app.BATTERYEnergyConsumptionTab);
            title(app.Battplot, 'Title')
            xlabel(app.Battplot, 'X')
            ylabel(app.Battplot, 'Y')
            zlabel(app.Battplot, 'Z')
            app.BatterySettingsPanel = uipanel(app.BATTERYEnergyConsumptionTab);
            app.BatterySettingsPanel.Title = 'Battery Settings';
            app.SelectthedesiredBatteryTypeandviewitspropertiesbelowLabel = uilabel(app.BatterySettingsPanel);
            app.SelectthedesiredBatteryTypeandviewitspropertiesbelowLabel.Text = 'Select the desired BatteryType and view its properties below.';
            app.BatteryTypesDropDownLabel = uilabel(app.BatterySettingsPanel);
            app.BatteryTypesDropDownLabel.Text = 'Battery Types';
            app.BatteryTypesDropDown = uidropdown(app.BatterySettingsPanel);
            app.BatteryTypesDropDown.Items = {'Toriyama', 'Toriyama2', 'Panasonic'};
            app.BatteryTypesDropDown.ValueChangedFcn = createCallbackFcn(app, @BatteryTypesDropDownValueChanged, true);
            app.BatteryTypesDropDown.Value = 'Toriyama';
            app.InitialSOCEditFieldLabel = uilabel(app.BatterySettingsPanel);
            app.InitialSOCEditFieldLabel.Text = 'Initial SOC [ %]';
            app.InitialSOCEditField = uieditfield(app.BatterySettingsPanel, 'numeric');
            app.InitialSOCEditField.ValueChangedFcn = createCallbackFcn(app, @BatteryTypesDropDownValueChanged, true);
            app.InitialSOCEditField.Value = 100;
            app.ParametersLabel = uilabel(app.BatterySettingsPanel);
            app.ParametersLabel.Text = 'Parameters';
            app.NumberofParallelCellsEditFieldLabel = uilabel(app.BatterySettingsPanel);
            app.NumberofParallelCellsEditFieldLabel.Text = 'Number of Parallel Cells [ -]';
            app.NumberofParallelCellsEditField = uieditfield(app.BatterySettingsPanel, 'numeric');
            app.NumberofParallelCellsEditField.ValueChangedFcn = createCallbackFcn(app, @BatteryTypesDropDownValueChanged, true);
            app.NumberofParallelCellsEditField.Value = 500;
            app.NumberofSeriesCellsEditFieldLabel = uilabel(app.BatterySettingsPanel);
            app.NumberofSeriesCellsEditFieldLabel.Text = 'Number of Series Cells [-]';
            app.NumberofSeriesCellsEditField = uieditfield(app.BatterySettingsPanel, 'numeric');
            app.NumberofSeriesCellsEditField.ValueChangedFcn = createCallbackFcn(app, @BatteryTypesDropDownValueChanged, true);
            app.NumberofSeriesCellsEditField.Value = 500;
            app.BattTable = uitable(app.BatterySettingsPanel);
            app.BattTable.ColumnName = {'Specifications'; 'Value'};
            app.BattTable.ColumnRearrangeable = 'on';
            app.BattTable.RowName = {};
            app.BattTable.ColumnSortable = true;
            app.BottomPanel = uipanel(app.UIFigure);
            app.ScopesDropDownLabel = uilabel(app.BottomPanel);
            app.ScopesDropDownLabel.Text = 'Scopes';
            app.ScopesDropDown = uidropdown(app.BottomPanel);
            app.ScopesDropDown.Items = {'Battery', 'fuelcell'};
            app.ScopesDropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.ScopesDropDown.Value = 'Battery';
            app.TimeScope = uitimescope(app.BottomPanel);
            app.TimeScope.LegendNames = {'P_battery'; 'P_electrical'; 'P_fuelcell'};
            app.TimeScope.LegendVisible = 'on';
            app.TimeScope.Title = 'Power Demand Results ';
            app.TimeScope.XLabel = 'Time ';
            app.TimeScope.YLabel = 'Power (W)';
            app.TimeScope.XTimeSpan = 10000;
            app.TimeScope2 = uitimescope(app.BottomPanel);
            app.TimeScope2.LegendNames = {'Simulator/Fuel Cell/1//HHV_H2):1'};
            app.TimeScope2.Title = 'Fuel Cell: Hydrogen Mass Flow';
            app.TimeScope2.YLabel = 'Mass Flow (kg/s)';
            app.TimeScope2.XTimeSpan = 10000;
            app.TimeScope3 = uitimescope(app.BottomPanel);
            app.TimeScope3.LegendNames = {''; 'Hydrogen Mass'};
            app.TimeScope3.Title = 'Fuel Cell: Hydrogen Mass';
            app.TimeScope3.YLabel = 'Hydrogen Mass (kg)';
            app.TimeScope3.XTimeSpan = 10000;
            app.TimeScope5 = uitimescope(app.BottomPanel);
            app.TimeScope5.Title = ' Thrust';
            app.TimeScope5.YLabel = 'Thrust (N)';
            app.TimeScope5.XTimeSpan = 10000;
            app.LoggedSignalLabel = uilabel(app.BottomPanel);
            app.LoggedSignalLabel.Text = 'Logged Signal';
            app.TimeScope7 = uitimescope(app.BottomPanel);
            app.TimeScope7.LegendNames = {'Simulator/Battery/J to kWh1:1'};
            app.TimeScope7.Title = 'Battery: State of Charge';
            app.TimeScope7.YLabel = 'SOC (%)';
            app.TimeScope7.XTimeSpan = 10000;
            app.TimeScope6 = uitimescope(app.BottomPanel);
            app.TimeScope6.Title = 'Battery Energy Consumption';
            app.TimeScope6.YLabel = 'Energy Consumption kWh';
            app.TimeScope6.XTimeSpan = 10000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Simulator/Battery/J to kWh:1'], app.TimeScope6);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Simulator/Battery/J to kWh1:1'], app.TimeScope7);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Simulator/EMS /Divide:1'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Simulator/EMS /In1:1'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Simulator/EMS /Subtract:1'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Simulator/Fuel Cell/1//HHV_H2):1'], app.TimeScope2);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Simulator/Fuel Cell/Memory2:1'], app.TimeScope3);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Simulator/Propeller/Subsystem/Switch:1'], app.TimeScope5);
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Associate the Simulink Model
            app.Simulation = simulation('model6');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end